classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        FunctionDropDownLabel           matlab.ui.control.Label
        FunctionDropDown                matlab.ui.control.DropDown
        InputsPanel                     matlab.ui.container.Panel
        ObservationsMLabel              matlab.ui.control.Label
        ObservationsMSpinner            matlab.ui.control.Spinner
        ThicknessstepmmEditFieldLabel   matlab.ui.control.Label
        ThicknessstepmmEditField        matlab.ui.control.NumericEditField
        IncidentangledegreeEditFieldLabel  matlab.ui.control.Label
        IncidentangledegreeEditField    matlab.ui.control.NumericEditField
        FrequenciesGHzEditFieldLabel    matlab.ui.control.Label
        FrequenciesGHzEditField         matlab.ui.control.EditField
        EnvironmentPropertiesPanel      matlab.ui.container.Panel
        RoughnessEditFieldLabel         matlab.ui.control.Label
        RoughnessEditField              matlab.ui.control.NumericEditField
        OildielectricEditFieldLabel     matlab.ui.control.Label
        OildielectricEditField          matlab.ui.control.NumericEditField
        WaterdielectricEditFieldLabel   matlab.ui.control.Label
        WaterdielectricEditField        matlab.ui.control.NumericEditField
        WaterTempertatureCEditFieldLabel  matlab.ui.control.Label
        WaterTempertatureCEditField     matlab.ui.control.NumericEditField
        WaterSalinitypptEditFieldLabel  matlab.ui.control.Label
        WaterSalinitypptEditField       matlab.ui.control.NumericEditField
        NoisePropertiesPanel            matlab.ui.container.Panel
        VarianceEditFieldLabel          matlab.ui.control.Label
        VarianceEditField               matlab.ui.control.NumericEditField
        SamplesSliderLabel              matlab.ui.control.Label
        SamplesSlider                   matlab.ui.control.Slider
        SpillButton                     matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        AdditionalPlotsPanel            matlab.ui.container.Panel
        OptionsDropDownLabel            matlab.ui.control.Label
        OptionsDropDown                 matlab.ui.control.DropDown
        GenerateforallfrequencytriadsButton  matlab.ui.control.Button
        GenerateforallfrequencypairsButton  matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        ShowhistogramButton             matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Plots % Description
    end
    
    
    methods (Access = private)
        
        function [row,col,R] = fractalsurface(n,H)
            % inputs:
            % n - number of iterations
            % H - Hurst exponent, fractal dimension D = 3-H
            % outputs:
            % row - row location of surface
            % col - column location of surface
            % R - surface matrix
            % Authors: Hui Yang
            % Affiliations:
            % The Pennsylvania State University
            % 310 Leohard Building, University Park, PA
            % Email: yanghui@gmail.com
            % If you find this toolbox useful, please cite the following papers:
            % [1]	Y. Chen and H. Yang, Numerical simulation and pattern characterization of
            % nonlinear spatiotemporal dynamics on fractal surfaces for the whole-heart modeling
            % applications, European Physical Journal, DOI: 10.1140/epjb/e2016-60960-6
            % [2]	B. Yao, F. Imani, A. Sakpal, E. W. Reutzel, and H. Yang*, �Multifractal
            % analysis of image profiles for the characterization and detection of defects
            % in additive manufacturing,� ASME Journal of Manufacturing Science and Engineering,
            % Vol. 140, No. 3, p031014-13, 2017, DOI: 10.1115/1.4037891
            % [3]	F. Imani, B. Yao, R. Chen, P. Rao, and H. Yang*, �Joint multifractal
            % and lacunarity analysis of image profiles for manufacturing quality control�.
            % ASME Journal Manufacturing Science and Engineering, Vol. 141, No. 4,
            % p 044501-7, 2019. DOI: 10.1115/1.4042579
            N=2^n; % size of surface is (N+1)*(N+1)
            L=zeros(N+1,N+1); % initial of surface
            %% first iteration
            R=randn(3,3);
            Initial = [1,2^(n-1)+1,2^n+1];
            for i=1:3
                for j=1:3
                    L(Initial(i),Initial(j))=R(i,j);
                end
            end
            [row,col]=find(L); % location of first iteration
            r=sqrt(length(row));
            %% generation of the rest iterations
            for l=1:n-1
                for i=1:r-1
                    mid(i)=(row(i)+row(i+1))/2;
                    for j=1:r
                        L(mid(i),row(j))=mean([L(row(i),row(j)),L(row(i+1),row(j))])+randn*2^(-H*(l+1));
                        L(row(j),mid(i))=mean([L(row(j),row(i)),L(row(j),row(i+1))])+randn*2^(-H*(l+1));
                    end
                end
                for i=1:r-1
                    for j=1:r-1
                        L(mid(i),mid(j))=mean([L(row(i),row(j)),L(row(i),row(j+1)),L(row(i+1),row(j)),L(row(i+1),row(j+1))])+randn*2^(-H*(l+1));
                    end
                end
                
                [row,col]=find(L);
                r=sqrt(length(row));
                
            end
            R=zeros(r,r);
            for i=1:r
                for j=1:r
                    R(i,j)=L(row(i),row(j));
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: 
        % GenerateforallfrequencytriadsButton
        function GenerateforallfrequencytriadsButtonPushed(app, event)
            %calculate histogram values;
            h1 = 1;
            app.Plots = Plots(app, h1 )
        end

        % Button pushed function: GenerateforallfrequencypairsButton
        function GenerateforallfrequencypairsButtonPushed(app, event)
            %calculate histogram values;
            h1 = 1;
            app.Plots = Plots(app, h1 )
        end

        % Button pushed function: SpillButton
        function SpillButtonPushed(app, event)
            n=8;        % image resolution will be (2^n + 1)
            H=0.7;
            [~,~,G] = fractalsurface(n,H);
            G = normalize(G, 'range',[0 10]);
            clims = [min(G(:)), max(G(:))];
            
            title(app.UIAxes, 'Fractal surface');
            axis(app.UIAxes, "tight");
            colormap(app.UIAxes, 'jet');
            colorbar(app.UIAxes);
            imagesc(app.UIAxes, G);
            set(handles.G, G);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)

            frequency= str2double(strsplit(app.FrequenciesGHzEditField.Value,','));
            G = get(handles.G, G);
            reflectivities = double.empty;
            for i =1:1:length(frequency)
                reflectivities(:, :, i) = reflectivity(frequency(i), handles.G, ks, E_oil, E_air, temp, salinity, theta);
            end
            
            M = 5;
            tic
            y = Detection_probability(reflectivities, M, frequency, ks, variance, E_air, temp, salinity, theta);
            clims = [0, 1];
            imagesc(app.UIAxes2, y, clims);
            cmp = gray;
            cmp = flipud(cmp);
            colormap(app.UIAxes2, cmp);
            colorbar(app.UIAxes2)
            toc
            
            M = 50;
            tic
            Estimated_thickness = Estimate_Thickness(reflectivities, M, frequency, ks, thickness_step, variance, E_oil, E_air, temp, salinity, theta);
            clims = [0, 10];
            imagesc(app.UIAxes3, Estimated_thickness, clims);
            colormap(app.UIAxes3, "jet");
            colorbar(app.UIAxes3)
            toc
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {754, 754};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {609, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FunctionDropDownLabel = uilabel(app.LeftPanel);
            app.FunctionDropDownLabel.Text = 'Function';
            app.FunctionDropDown = uidropdown(app.LeftPanel);
            app.FunctionDropDown.Items = {'Simulate Spillage', 'Plot probability', 'Constellations'};
            app.FunctionDropDown.Value = 'Simulate Spillage';
            app.InputsPanel = uipanel(app.LeftPanel);
            app.InputsPanel.Title = ' Inputs';
            app.ObservationsMLabel = uilabel(app.InputsPanel);
            app.ObservationsMLabel.Text = 'Observations (M)';
            app.ObservationsMSpinner = uispinner(app.InputsPanel);
            app.ObservationsMSpinner.Limits = [1 Inf];
            app.ObservationsMSpinner.Value = 1;
            app.ThicknessstepmmEditFieldLabel = uilabel(app.InputsPanel);
            app.ThicknessstepmmEditFieldLabel.Text = 'Thickness step (mm)';
            app.ThicknessstepmmEditField = uieditfield(app.InputsPanel, 'numeric');
            app.IncidentangledegreeEditFieldLabel = uilabel(app.InputsPanel);
            app.IncidentangledegreeEditFieldLabel.Text = 'Incident angle (degree)';
            app.IncidentangledegreeEditField = uieditfield(app.InputsPanel, 'numeric');
            app.FrequenciesGHzEditFieldLabel = uilabel(app.InputsPanel);
            app.FrequenciesGHzEditFieldLabel.Text = 'Frequencies (GHz)';
            app.FrequenciesGHzEditField = uieditfield(app.InputsPanel, 'text');
            app.EnvironmentPropertiesPanel = uipanel(app.LeftPanel);
            app.EnvironmentPropertiesPanel.Title = ' Environment Properties';
            app.RoughnessEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.RoughnessEditFieldLabel.Text = 'Roughness';
            app.RoughnessEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.OildielectricEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.OildielectricEditFieldLabel.Text = 'Oil dielectric';
            app.OildielectricEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.WaterdielectricEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.WaterdielectricEditFieldLabel.Text = 'Water dielectric';
            app.WaterdielectricEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.WaterTempertatureCEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.WaterTempertatureCEditFieldLabel.Text = 'Water Tempertature (°C)';
            app.WaterTempertatureCEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.WaterSalinitypptEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.WaterSalinitypptEditFieldLabel.Text = 'Water Salinity (ppt)';
            app.WaterSalinitypptEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.NoisePropertiesPanel = uipanel(app.LeftPanel);
            app.NoisePropertiesPanel.Title = ' Noise Properties';
            app.VarianceEditFieldLabel = uilabel(app.NoisePropertiesPanel);
            app.VarianceEditFieldLabel.Text = 'Variance';
            app.VarianceEditField = uieditfield(app.NoisePropertiesPanel, 'numeric');
            app.SamplesSliderLabel = uilabel(app.NoisePropertiesPanel);
            app.SamplesSliderLabel.Text = 'Samples';
            app.SamplesSlider = uislider(app.NoisePropertiesPanel);
            app.SamplesSlider.Limits = [0 3000];
            app.SpillButton = uibutton(app.LeftPanel, 'push');
            app.SpillButton.ButtonPushedFcn = createCallbackFcn(app, @SpillButtonPushed, true);
            app.SpillButton.Text = 'Spill';
            app.CalculateButton = uibutton(app.LeftPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.AdditionalPlotsPanel = uipanel(app.LeftPanel);
            app.AdditionalPlotsPanel.Title = ' Additional Plots';
            app.OptionsDropDownLabel = uilabel(app.AdditionalPlotsPanel);
            app.OptionsDropDownLabel.Text = 'Options';
            app.OptionsDropDown = uidropdown(app.AdditionalPlotsPanel);
            app.OptionsDropDown.Items = {'Probability of detection', 'Thickness Estimation', 'Both'};
            app.OptionsDropDown.Value = 'Probability of detection';
            app.GenerateforallfrequencytriadsButton = uibutton(app.AdditionalPlotsPanel, 'push');
            app.GenerateforallfrequencytriadsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateforallfrequencytriadsButtonPushed, true);
            app.GenerateforallfrequencytriadsButton.Text = 'Generate for all frequency triads';
            app.GenerateforallfrequencypairsButton = uibutton(app.AdditionalPlotsPanel, 'push');
            app.GenerateforallfrequencypairsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateforallfrequencypairsButtonPushed, true);
            app.GenerateforallfrequencypairsButton.Text = 'Generate for all frequency pairs';
            app.RightPanel = uipanel(app.GridLayout);
            app.ShowhistogramButton = uibutton(app.RightPanel, 'push');
            app.ShowhistogramButton.Text = 'Show histogram';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end